classdef uygulamaprojesteskop < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Label2                    matlab.ui.control.Label
        RPMLabel                  matlab.ui.control.Label
        BPMLabel                  matlab.ui.control.Label
        LableLabel                matlab.ui.control.Label
        EditOrderEditField        matlab.ui.control.NumericEditField
        EditOrderEditFieldLabel   matlab.ui.control.Label
        LowpassfcEditField        matlab.ui.control.NumericEditField
        LowpassfcEditFieldLabel   matlab.ui.control.Label
        HighpassfcEditField       matlab.ui.control.NumericEditField
        HighpassfcEditFieldLabel  matlab.ui.control.Label
        StopButton                matlab.ui.control.Button
        StartButton               matlab.ui.control.Button
        Image                     matlab.ui.control.Image
        UIAxes_4                  matlab.ui.control.UIAxes
        UIAxes_3                  matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

     %Properties defined
     %Objects names pointed
     properties (Access = private)
        arduinoPObj 
        f
        f1
        Y
        Y_1
        P1
        P2
        P3
        P4
        filteredPlot
        rawPlot
        serialport
        Fs = 8000;
        f_s = 5000;
        fc_1 = 30;
        fc_2 = 200;
        T = 1/5000;
        n = 1;
        m = 1;
        m1 = 1;
        windowing = 2000;
        IsRunning = false;
        Timer
        Data
        ffData
        fdata 
        coefficient_1
        coefficient_2
        fc3=30
        bpm
        coefficient_3
        coefficient_4
        orderOfFilter=4;
        end
    %private method defined and timerCallback function is defined in it
    methods (Access = private)
        function timerCallback = func(app)
            if ~app.IsRunning
            stop(app.Timer);
        end
    end
 end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.IsRunning=true;         %startup fucntion builded to compile this codes when app starts
            app.Timer=timer;            %Timer's properties builded.
            app.Timer.Period=0.1;
            app.Timer.ExecutionMode='fixedRate';
            app.Timer.TimerFcn=@(~,~)app.timerCallback;
            start(app.Timer);

            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.IsRunning = true;           %bool IsRunning true
            app.arduinoPObj = serialport("COM3", 9600); %builded the arduino object
            configureTerminator(app.arduinoPObj, "CR/LF"); % terminator defined for arduino objects data control.
            flush(app.arduinoPObj); %clean previos datas in arduino if there is. reflesh arduino.
            app.arduinoPObj.UserData = struct("Data", [], "Order", 1, "fData", [],"ffData",[]);%structor defined for
            app.IsRunning = true;                                                               %data storage
            %general while loop for main codes                                                  %arrays defined for different datas.
            while app.arduinoPObj.UserData.Order < 100000
                    app.Data = readline(app.arduinoPObj);%reading data from arduino with ASCII datatype
                    app.arduinoPObj.UserData.Data(end+1) = str2double(app.Data);%converting the datas to double
                    app.arduinoPObj.UserData.Order = app.arduinoPObj.UserData.Order + 1;
                if mod(app.arduinoPObj.UserData.Order, 10) == 0 %if the modof Order==0 run this block
                    configureCallback(app.arduinoPObj, "off");
                    %plotting for raw data signal
                    %filter definition and implementation
                    plot(app.UIAxes, app.arduinoPObj.UserData.Data(max(1,end-app.windowing+1):end))
                      [app.coefficient_1, app.coefficient_2] = butter(4, app.fc3/(app.f_s/2), 'high');
        app.arduinoPObj.UserData.ffData = filter(app.coefficient_1, app.coefficient_2, app.arduinoPObj.UserData.Data(1:end));
                    [app.coefficient_3, app.coefficient_4] = butter(app.orderOfFilter, [app.fc_1/(app.f_s/2), app.fc_2/(app.f_s/2)], 'bandpass');
                    
                    app.arduinoPObj.UserData.fData = filter(app.coefficient_3, app.coefficient_4, app.arduinoPObj.UserData.Data);
                    %ploting of filtered signal
                    plot(app.UIAxes_2, app.arduinoPObj.UserData.fData(max(1, end-app.windowing+1):end) * 3);
                    drawnow;
                    
                    configureTerminator(app.arduinoPObj, "CR/LF");

                end
                 if mod(app.arduinoPObj.UserData.Order,500)==0 %if mod of order==500 run this block
                     configureCallback(app.arduinoPObj, "off");
                     %fft definitions and configurations
                     app.Y = fft(app.arduinoPObj.UserData.ffData(end-499+1:end));
                     app.P2 = abs(app.Y / 500); 
                     app.f = app.f_s * (0:(length(app.Y)/2))/length(app.Y);
                     app.P1 = app.P2(1:length(app.Y)/2+1);
                     app.P1(2:end-1) = 2 * app.P1(2:end-1);
                     plot(app.UIAxes_3,app.f,app.P1,'Color','r');
                     set(gca,'ylim',[0,10]);
                     %
                     app.Y_1 = fft(app.arduinoPObj.UserData.fData(end-499+1:end));
                     app.P3 = abs(app.Y_1 / 500); %
                     app.f1 = app.f_s * (0:(length(app.Y_1)/2))/length(app.Y_1); % Adjust frequency axis
                     app.P4 = app.P3(1:length(app.Y_1)/2+1);
                     app.P4(2:end-1) = 2 * app.P4(2:end-1);
                     plot(app.UIAxes_4, app.f1,app.P4,'Color','r');
                     set(gca,'ylim',[0,15]);

                     grid on;
                     app.m=app.m+500;
                     configureTerminator(app.arduinoPObj,"CR/LF");
                 end 
                 % if mod of order==1500 run this block
                   if mod(app.arduinoPObj.UserData.Order, 1500) == 0
                     % Measure elapsed time
                    timeAtPointBPM = toc;
                    % Reset the timer immediately for the next interval
                     
                
                    % Detect peaks in the data
                    threshold = 490; % Adjust this threshold as needed
                    [peaks, ~] = findpeaks(app.arduinoPObj.UserData.Data(end-1499+1:end), 'MinPeakHeight', threshold);
                    numPeaks = numel(peaks); % Count the number of peaks
                
                    % Calculate BPM
                    app.bpm = (60 * numPeaks) / timeAtPointBPM; % BPM calculation
                    disp(int16(app.bpm)); % Display BPM
                    tic;
                    app.LableLabel.Text = num2str(app.bpm);
                    end
                end

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.IsRunning=false; %bool IsRunning=false
            delete(app.arduinoPObj);%delete arduino object
        end

        % Value changed function: LowpassfcEditField
        function LowpassfcEditFieldValueChanged(app, event)
            b=app.LowpassfcEditField.Value; %get data from cutoff boxes
            app.fc_2=b; %assing it to fc_2
        end

        % Value changed function: HighpassfcEditField
        function HighpassfcEditFieldValueChanged(app, event)
             c= app.HighpassfcEditField.Value;%get data from cutoff boxes
             app.fc_1=c;    %assing it to fc_2
        end

        % Value changed function: EditOrderEditField
        function EditOrderEditFieldValueChanged(app, event)
            j = app.EditOrderEditField.Value; %get order that from box
            app.orderOfFilter=j; %assing it to orderOfFilter 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Raw Signal')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Filtered Signal')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'Amplitude')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Raw Signal FFT')
            xlabel(app.UIAxes_3, 'Frequency')
            ylabel(app.UIAxes_3, 'Amplitude of Frequency')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Filtered Signal FFT')
            xlabel(app.UIAxes_4, 'Frequency')
            ylabel(app.UIAxes_4, 'Amplitude of Frequency')
            zlabel(app.UIAxes_4, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'indir.jpeg';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.HighpassfcEditFieldLabel = uilabel(app.UIFigure);
            app.HighpassfcEditFieldLabel.Text = 'High pass fc';
            app.HighpassfcEditField = uieditfield(app.UIFigure, 'numeric');
            app.HighpassfcEditField.ValueChangedFcn = createCallbackFcn(app, @HighpassfcEditFieldValueChanged, true);
            app.HighpassfcEditField.Value = 30;
            app.LowpassfcEditFieldLabel = uilabel(app.UIFigure);
            app.LowpassfcEditFieldLabel.Text = 'Low pass fc';
            app.LowpassfcEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowpassfcEditField.ValueChangedFcn = createCallbackFcn(app, @LowpassfcEditFieldValueChanged, true);
            app.LowpassfcEditField.Value = 200;
            app.EditOrderEditFieldLabel = uilabel(app.UIFigure);
            app.EditOrderEditFieldLabel.Text = 'Edit Order';
            app.EditOrderEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditOrderEditField.ValueChangedFcn = createCallbackFcn(app, @EditOrderEditFieldValueChanged, true);
            app.EditOrderEditField.Value = 4;
            app.LableLabel = uilabel(app.UIFigure);
            app.LableLabel.Text = 'Lable';
            app.BPMLabel = uilabel(app.UIFigure);
            app.BPMLabel.Text = 'BPM';
            app.RPMLabel = uilabel(app.UIFigure);
            app.RPMLabel.Text = 'RPM';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uygulamaprojesteskop

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end